classdef A2GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        UR3Tab              matlab.ui.container.Tab
        q1Slider            matlab.ui.control.Slider
        q1SliderLabel       matlab.ui.control.Label
        q2Slider            matlab.ui.control.Slider
        q2SliderLabel       matlab.ui.control.Label
        q3Slider            matlab.ui.control.Slider
        q3SliderLabel       matlab.ui.control.Label
        q4Slider            matlab.ui.control.Slider
        q4SliderLabel       matlab.ui.control.Label
        q5Slider            matlab.ui.control.Slider
        q5SliderLabel       matlab.ui.control.Label
        q6Slider            matlab.ui.control.Slider
        q6SliderLabel       matlab.ui.control.Label
        BackwardsButton_3   matlab.ui.control.Button
        ForwardButton_3     matlab.ui.control.Button
        DOWNButton_3        matlab.ui.control.Button
        LEFTButton_3        matlab.ui.control.Button
        RIGHTButton_3       matlab.ui.control.Button
        UPButton_3          matlab.ui.control.Button
        UR20Tab             matlab.ui.container.Tab
        BackwardsButton_4   matlab.ui.control.Button
        ForwardButton_4     matlab.ui.control.Button
        DOWNButton_4        matlab.ui.control.Button
        LEFTButton_4        matlab.ui.control.Button
        RIGHTButton_4       matlab.ui.control.Button
        UPButton_4          matlab.ui.control.Button
        q1Slider_2Label     matlab.ui.control.Label
        q1Slider_2          matlab.ui.control.Slider
        q2Slider_2Label     matlab.ui.control.Label
        q2Slider_2          matlab.ui.control.Slider
        q3Slider_2Label     matlab.ui.control.Label
        q3Slider_2          matlab.ui.control.Slider
        q4Slider_2Label     matlab.ui.control.Label
        q4Slider_2          matlab.ui.control.Slider
        q5Slider_2Label     matlab.ui.control.Label
        q5Slider_2          matlab.ui.control.Slider
        q6Slider_2Label     matlab.ui.control.Label
        q6Slider_2          matlab.ui.control.Slider
        Lamp_2              matlab.ui.control.Lamp
        ESTOPButton_2       matlab.ui.control.StateButton
        startpauseButton_2  matlab.ui.control.Button
        LoadobstacleButton  matlab.ui.control.StateButton
        TextArea            matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        status = 0;% Description
        stop = false; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function startpauseButtonPushed(app, event)
            disp("YAY");
            
        end

        % Value changed function: ESTOPButton_2
        function ESTOPButton_2ValueChanged(app, event)
            value = app.ESTOPButton_2.Value;
            if value                                                        %if button pressed turn light red
                app.Lamp_2.Color = [1 0 0];                                 
                app.stop = true;
                app.status = 3;
                text = sprintf('stopped');
            else                                                            %else button is not pressed
                app.stop = false;
                app.Lamp_2.Color = [1 1 0];
                text = sprintf('paused');
            end
            assignin("base","status",app.status);
            app.TextArea.Value = text;
        end

        % Button pushed function: startpauseButton_2
        function startpauseButton_2Pushed(app, event)
            if app.status == 0 || app.status == 2 && app.stop == false      %if status off or paused and estop not toggled on turn light green
                app.Lamp_2.Color = [0 1 0];
                app.status = 1;
                text = sprintf('on');
            elseif app.status == 3 && app.stop == false                     %if status stopped and estop not toggled on turn light green
                app.Lamp_2.Color = [0 1 0];
                app.status = 1;
                text = sprintf('on');
            elseif app.stop == false && app.status == 1                     %if estop not on and status is on, turn to pause
                app.Lamp_2.Color = [1 1 0];
                app.status = 2;
                text = sprintf('paused');
            else
                text = sprintf('stopped');
            end
            assignin("base","status",app.status);
            app.TextArea.Value = text;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UR3Tab = uitab(app.TabGroup);
            app.UR3Tab.Title = 'UR3';
            app.q1Slider = uislider(app.UR3Tab);
            app.q1SliderLabel = uilabel(app.UR3Tab);
            app.q1SliderLabel.Text = 'q1';
            app.q2Slider = uislider(app.UR3Tab);
            app.q2SliderLabel = uilabel(app.UR3Tab);
            app.q2SliderLabel.Text = 'q2';
            app.q3Slider = uislider(app.UR3Tab);
            app.q3SliderLabel = uilabel(app.UR3Tab);
            app.q3SliderLabel.Text = 'q3';
            app.q4Slider = uislider(app.UR3Tab);
            app.q4SliderLabel = uilabel(app.UR3Tab);
            app.q4SliderLabel.Text = 'q4';
            app.q5Slider = uislider(app.UR3Tab);
            app.q5SliderLabel = uilabel(app.UR3Tab);
            app.q5SliderLabel.Text = 'q5';
            app.q6Slider = uislider(app.UR3Tab);
            app.q6SliderLabel = uilabel(app.UR3Tab);
            app.q6SliderLabel.Text = 'q6';
            app.BackwardsButton_3 = uibutton(app.UR3Tab, 'push');
            app.BackwardsButton_3.Text = 'Backwards';
            app.ForwardButton_3 = uibutton(app.UR3Tab, 'push');
            app.ForwardButton_3.Text = 'Forward';
            app.DOWNButton_3 = uibutton(app.UR3Tab, 'push');
            app.DOWNButton_3.Text = 'DOWN';
            app.LEFTButton_3 = uibutton(app.UR3Tab, 'push');
            app.LEFTButton_3.Text = 'LEFT';
            app.RIGHTButton_3 = uibutton(app.UR3Tab, 'push');
            app.RIGHTButton_3.Text = 'RIGHT';
            app.UPButton_3 = uibutton(app.UR3Tab, 'push');
            app.UPButton_3.Text = 'UP';
            app.UR20Tab = uitab(app.TabGroup);
            app.UR20Tab.Title = 'UR20';
            app.BackwardsButton_4 = uibutton(app.UR20Tab, 'push');
            app.BackwardsButton_4.Text = 'Backwards';
            app.ForwardButton_4 = uibutton(app.UR20Tab, 'push');
            app.ForwardButton_4.Text = 'Forward';
            app.DOWNButton_4 = uibutton(app.UR20Tab, 'push');
            app.DOWNButton_4.Text = 'DOWN';
            app.LEFTButton_4 = uibutton(app.UR20Tab, 'push');
            app.LEFTButton_4.Text = 'LEFT';
            app.RIGHTButton_4 = uibutton(app.UR20Tab, 'push');
            app.RIGHTButton_4.Text = 'RIGHT';
            app.UPButton_4 = uibutton(app.UR20Tab, 'push');
            app.UPButton_4.Text = 'UP';
            app.q1Slider_2Label = uilabel(app.UR20Tab);
            app.q1Slider_2Label.Text = 'q1';
            app.q1Slider_2 = uislider(app.UR20Tab);
            app.q2Slider_2Label = uilabel(app.UR20Tab);
            app.q2Slider_2Label.Text = 'q2';
            app.q2Slider_2 = uislider(app.UR20Tab);
            app.q3Slider_2Label = uilabel(app.UR20Tab);
            app.q3Slider_2Label.Text = 'q3';
            app.q3Slider_2 = uislider(app.UR20Tab);
            app.q4Slider_2Label = uilabel(app.UR20Tab);
            app.q4Slider_2Label.Text = 'q4';
            app.q4Slider_2 = uislider(app.UR20Tab);
            app.q5Slider_2Label = uilabel(app.UR20Tab);
            app.q5Slider_2Label.Text = 'q5';
            app.q5Slider_2 = uislider(app.UR20Tab);
            app.q6Slider_2Label = uilabel(app.UR20Tab);
            app.q6Slider_2Label.Text = 'q6';
            app.q6Slider_2 = uislider(app.UR20Tab);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_2.Tooltip = {''};
            app.ESTOPButton_2 = uibutton(app.UIFigure, 'state');
            app.ESTOPButton_2.ValueChangedFcn = createCallbackFcn(app, @ESTOPButton_2ValueChanged, true);
            app.ESTOPButton_2.Text = 'E STOP';
            app.startpauseButton_2 = uibutton(app.UIFigure, 'push');
            app.startpauseButton_2.ButtonPushedFcn = createCallbackFcn(app, @startpauseButton_2Pushed, true);
            app.startpauseButton_2.Text = 'start/pause';
            app.LoadobstacleButton = uibutton(app.UIFigure, 'state');
            app.LoadobstacleButton.Text = 'Load obstacle';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'off'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A2GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end